classdef signal_image_processing < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        SharpingButton     matlab.ui.control.Button
        SelectimageButton  matlab.ui.control.Button
        UIAxes_2           matlab.ui.control.UIAxes
        UIAxes             matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectimageButton
        function SelectimageButtonPushed(app, event)
            
        end

        % Button pushed function: SharpingButton
        function SharpingButtonPushed(app, event)
            app.SharpenImageButton = uibutton(app.UIFigure, 'push');
            app.SharpenImageButton.Position = [151, 22, 100, 30];
            app.SharpenImageButton.Text = 'Sharpen Image';
            app.SharpenImageButton.ButtonPushedFcn = createCallbackFcn(app, @sharpenImageButtonPushed, true);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes_2 = uiaxes(app.UIFigure);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.SelectimageButton = uibutton(app.UIFigure, 'push');
            app.SelectimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimageButtonPushed, true);
            app.SelectimageButton.Text = 'Select image';
            app.SharpingButton = uibutton(app.UIFigure, 'push');
            app.SharpingButton.ButtonPushedFcn = createCallbackFcn(app, @SharpingButtonPushed, true);
            app.SharpingButton.Text = 'Sharping';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = signal_image_processing

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end